classdef ImgRegSeg_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SettingsforpunctasizeadjustmentPanel  matlab.ui.container.Panel
        CameraPixelSizeinmEditFieldLabel  matlab.ui.control.Label
        CamPxlSiz                      matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        Bin                            matlab.ui.control.NumericEditField
        MaximumSizeinmEditFieldLabel   matlab.ui.control.Label
        MaxSiz                         matlab.ui.control.NumericEditField
        MinimumSizeinmEditFieldLabel   matlab.ui.control.Label
        MinSiz                         matlab.ui.control.NumericEditField
        MagnificationEditFieldLabel    matlab.ui.control.Label
        Mag                            matlab.ui.control.NumericEditField
        SettingsforpreprocessingPanel  matlab.ui.container.Panel
        discard                        matlab.ui.control.CheckBox
        denoise                        matlab.ui.control.CheckBox
        show                           matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        IterativethresholdingPanel     matlab.ui.container.Panel
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        nbIt                           matlab.ui.control.NumericEditField
        FrameRangeCr                   matlab.ui.container.Panel
        FromLabel                      matlab.ui.control.Label
        frame1                         matlab.ui.control.NumericEditField
        toLabel                        matlab.ui.control.Label
        frame2                         matlab.ui.control.NumericEditField
        FuncAdd                        matlab.ui.container.Panel
        Align                          matlab.ui.control.CheckBox
        bleaching                      matlab.ui.control.CheckBox
        FromLabel_2                    matlab.ui.control.Label
        startSec                       matlab.ui.control.NumericEditField
        toLabel_2                      matlab.ui.control.Label
        stopSec                        matlab.ui.control.NumericEditField
        SectionforbleachingcorrectionLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Preprocessing % Description
        Iteration
        SizeSettings
        FrameRange
        Func
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.Preprocessing, app.Iteration, app.SizeSettings, app.FrameRange. app.Func] = ...
                configuration_ImgRegSeg();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Preprocessing.Discard = app.discard.Value;
            app.Preprocessing.Denoise = app.denoise.Value;
            app.Preprocessing.ShowImage = app.show.Value;
            app.Iteration.it = app.nbIt.Value;   
            app.SizeSettings.CamPixelSize = app.CamPxlSiz.Value;
            app.SizeSettings.Magnification = app.Mag.Value;
            app.SizeSettings.Binning = app.Bin.Value;
            app.SizeSettings.MaxSize = app.MaxSiz.Value;
            app.SizeSettings.MinSize = app.MinSiz.Value;
            app.FrameRange.Frame1 = app.frame1.Value;
            app.FrameRange.Frame2 = app.frame2.Value;
            app.Func.Align = app.Align.Value;
            app.Func.bleaching = app.bleaching.Value;
            
            ImgSegRout_v1(app.Preprocessing, app.Iteration, app.SizeSettings,...
                app.FrameRange, app.Func);
            
        end

        % Value changed function: bleaching
        function bleachingValueChanged(app, event)
            value = app.bleaching.Value;
            if value == 1
                app.startSec.Enable = 'on';
                app.stopSec.Enable = 'on';
            end                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SettingsforpunctasizeadjustmentPanel = uipanel(app.UIFigure);
            app.SettingsforpunctasizeadjustmentPanel.Title = 'Settings for puncta size adjustment';
            app.CameraPixelSizeinmEditFieldLabel = uilabel(app.SettingsforpunctasizeadjustmentPanel);
            app.CameraPixelSizeinmEditFieldLabel.Text = 'Camera Pixel Size in µm';
            app.CamPxlSiz = uieditfield(app.SettingsforpunctasizeadjustmentPanel, 'numeric');
            app.CamPxlSiz.Limits = [0 100];
            app.CamPxlSiz.Value = 13;
            app.BinningEditFieldLabel = uilabel(app.SettingsforpunctasizeadjustmentPanel);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.Bin = uieditfield(app.SettingsforpunctasizeadjustmentPanel, 'numeric');
            app.Bin.Limits = [1 4];
            app.Bin.Value = 1;
            app.MaximumSizeinmEditFieldLabel = uilabel(app.SettingsforpunctasizeadjustmentPanel);
            app.MaximumSizeinmEditFieldLabel.Text = 'Maximum Size in µm';
            app.MaxSiz = uieditfield(app.SettingsforpunctasizeadjustmentPanel, 'numeric');
            app.MaxSiz.Limits = [0.01 100];
            app.MaxSiz.Value = 3;
            app.MinimumSizeinmEditFieldLabel = uilabel(app.SettingsforpunctasizeadjustmentPanel);
            app.MinimumSizeinmEditFieldLabel.Text = 'Minimum Size in µm';
            app.MinSiz = uieditfield(app.SettingsforpunctasizeadjustmentPanel, 'numeric');
            app.MinSiz.Value = 0.5;
            app.MagnificationEditFieldLabel = uilabel(app.SettingsforpunctasizeadjustmentPanel);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.Mag = uieditfield(app.SettingsforpunctasizeadjustmentPanel, 'numeric');
            app.Mag.Limits = [1 100];
            app.Mag.Value = 60;
            app.SettingsforpreprocessingPanel = uipanel(app.UIFigure);
            app.SettingsforpreprocessingPanel.Title = 'Settings for preprocessing';
            app.discard = uicheckbox(app.SettingsforpreprocessingPanel);
            app.discard.Text = 'Discard bright areas';
            app.denoise = uicheckbox(app.SettingsforpreprocessingPanel);
            app.denoise.Text = 'Denoising';
            app.show = uicheckbox(app.SettingsforpreprocessingPanel);
            app.show.Text = 'Show images';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.IterativethresholdingPanel = uipanel(app.UIFigure);
            app.IterativethresholdingPanel.Title = 'Iterative thresholding';
            app.NumberofiterationsEditFieldLabel = uilabel(app.IterativethresholdingPanel);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations ';
            app.nbIt = uieditfield(app.IterativethresholdingPanel, 'numeric');
            app.nbIt.Limits = [1 20];
            app.nbIt.RoundFractionalValues = 'on';
            app.nbIt.Value = 10;
            app.FrameRangeCr = uipanel(app.UIFigure);
            app.FrameRangeCr.Title = 'Frame  range to create template';
            app.FromLabel = uilabel(app.FrameRangeCr);
            app.FromLabel.Text = 'From';
            app.frame1 = uieditfield(app.FrameRangeCr, 'numeric');
            app.frame1.Limits = [1 Inf];
            app.frame1.Value = 1;
            app.toLabel = uilabel(app.FrameRangeCr);
            app.toLabel.Text = 'to';
            app.frame2 = uieditfield(app.FrameRangeCr, 'numeric');
            app.frame2.Limits = [1 Inf];
            app.frame2.Value = 120;
            app.FuncAdd = uipanel(app.UIFigure);
            app.FuncAdd.Title = 'Additional procedures';
            app.Align = uicheckbox(app.FuncAdd);
            app.Align.Text = 'Aligning';
            app.bleaching = uicheckbox(app.FuncAdd);
            app.bleaching.ValueChangedFcn = createCallbackFcn(app, @bleachingValueChanged, true);
            app.bleaching.Text = 'Correction for bleaching';
            app.FromLabel_2 = uilabel(app.FuncAdd);
            app.FromLabel_2.Enable = 'off';
            app.FromLabel_2.Text = 'From';
            app.startSec = uieditfield(app.FuncAdd, 'numeric');
            app.startSec.Limits = [1 Inf];
            app.startSec.Enable = 'off';
            app.startSec.Value = 10;
            app.toLabel_2 = uilabel(app.FuncAdd);
            app.toLabel_2.Enable = 'off';
            app.toLabel_2.Text = 'to';
            app.stopSec = uieditfield(app.FuncAdd, 'numeric');
            app.stopSec.Limits = [1 Inf];
            app.stopSec.Enable = 'off';
            app.stopSec.Value = 120;
            app.SectionforbleachingcorrectionLabel = uilabel(app.FuncAdd);
            app.SectionforbleachingcorrectionLabel.Text = 'Section for bleaching correction';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImgRegSeg_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end