classdef SynEval_GUIbeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SettingsforPunctaSizeAdjustmentPanel  matlab.ui.container.Panel
        CameraPixelSizeinmEditFieldLabel  matlab.ui.control.Label
        CamPxlSiz                      matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        Bin                            matlab.ui.control.NumericEditField
        MaximumSizeinmEditFieldLabel   matlab.ui.control.Label
        MaxSiz                         matlab.ui.control.NumericEditField
        MinimumSizeinmEditFieldLabel   matlab.ui.control.Label
        MinSiz                         matlab.ui.control.NumericEditField
        MagnificationEditFieldLabel    matlab.ui.control.Label
        Mag                            matlab.ui.control.NumericEditField
        SettingsforpreprocessingPanel  matlab.ui.container.Panel
        discard                        matlab.ui.control.CheckBox
        denoise                        matlab.ui.control.CheckBox
        show                           matlab.ui.control.CheckBox
        StartSegmentationButton        matlab.ui.control.Button
        IterativeThresholdingPanel     matlab.ui.container.Panel
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        nbIt                           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        LoadExampleImageButton         matlab.ui.control.Button
        checkiterationButton           matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Preprocessing % Description
        Iteration
        SizeSettings
        imgTest      
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.Preprocessing, app.Iteration, app.SizeSettings] = configuration();
            
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            
            title(app.UIAxes_2, []);
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            
            axis(app.UIAxes,'off')
            axis(app.UIAxes_2,'off')
        end

        % Button pushed function: StartSegmentationButton
        function StartSegmentationButtonPushed(app, event)
            app.Preprocessing.Discard = app.discard.Value;
            app.Preprocessing.Denoise = app.denoise.Value;
            app.Preprocessing.ShowImage = app.show.Value;
            app.Iteration.it = app.nbIt.Value;   
            app.SizeSettings.CamPixelSize = app.CamPxlSiz.Value;
            app.SizeSettings.Magnification = app.Mag.Value;
            app.SizeSettings.Binning = app.Bin.Value;
            app.SizeSettings.MaxSize = app.MaxSiz.Value;
            app.SizeSettings.MinSize = app.MinSiz.Value;
            
            [resultCell] = SynEval_v1_1(app.Preprocessing, app.Iteration, app.SizeSettings);
            
        end

        % Button pushed function: LoadExampleImageButton
        function LoadExampleImageButtonPushed(app, event)
%             app.Preprocessing.Discard = app.discard.Value;
%             app.Preprocessing.Denoise = app.denoise.Value;
%             app.Preprocessing.ShowImage = app.show.Value;
            
            [testInfo, testPath] = uigetfile('*.tif','select testimage','MultiSelect','off');
            testComplete = fullfile(testPath,testInfo);
            if ~iscell(testComplete)
                testComplete = cellstr(testComplete);
            end
            imgTest_ = double(imread(testComplete{1,1}));
            
            imgTest_ = PreProcessing(imgTest_, app.discard.Value, app.denoise.Value, app.show.Value);
            app.imgTest = imgTest_;
            
            % show
            relation = size(imgTest_,1)/size(imgTest_,2);
            I = imshow(imgTest_,'DisplayRange',[min(imgTest_(:)) max(imgTest_(:))], 'Parent', app.UIAxes,...
                'XData', [1 app.UIAxes.Position(4)*relation], 'YData', [1 app.UIAxes.Position(4)]);
            
            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: checkiterationButton
        function checkiterationButtonPushed(app, event)
            it = app.nbIt.Value;   
            app.SizeSettings.CamPixelSize = app.CamPxlSiz.Value;
            app.SizeSettings.Magnification = app.Mag.Value;
            app.SizeSettings.Binning = app.Bin.Value;
            app.SizeSettings.MaxSize = app.MaxSiz.Value;
            app.SizeSettings.MinSize = app.MinSiz.Value;
            
            [minArea,maxArea] = convert2pixel(app.SizeSettings); 
            
            [regList,BW, ~] = cellDetection_iterative_v1(app.imgTest,minArea,maxArea,it);
            segmImg = showSegmentation (app.imgTest ,BW, regList);
            relation = size(app.imgTest,1)/size(app.imgTest,2);
            I = imshow(segmImg,'DisplayRange',[min(app.imgTest(:)) max(app.imgTest(:))], 'Parent', app.UIAxes_2,...
                'XData', [1 app.UIAxes_2.Position(4)*relation], 'YData', [1 app.UIAxes_2.Position(4)]);
            
            
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SettingsforPunctaSizeAdjustmentPanel = uipanel(app.UIFigure);
            app.SettingsforPunctaSizeAdjustmentPanel.Title = 'Settings for Puncta Size Adjustment';
            app.CameraPixelSizeinmEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.CameraPixelSizeinmEditFieldLabel.Text = 'Camera Pixel Size in µm';
            app.CamPxlSiz = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.CamPxlSiz.Limits = [0 100];
            app.CamPxlSiz.Value = 13;
            app.BinningEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.Bin = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.Bin.Limits = [1 4];
            app.Bin.Value = 1;
            app.MaximumSizeinmEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.MaximumSizeinmEditFieldLabel.Text = 'Maximum Size in µm';
            app.MaxSiz = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.MaxSiz.Limits = [0.01 100];
            app.MaxSiz.Value = 3;
            app.MinimumSizeinmEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.MinimumSizeinmEditFieldLabel.Text = 'Minimum Size in µm';
            app.MinSiz = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.MinSiz.Value = 0.5;
            app.MagnificationEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.Mag = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.Mag.Limits = [1 100];
            app.Mag.Value = 60;
            app.SettingsforpreprocessingPanel = uipanel(app.UIFigure);
            app.SettingsforpreprocessingPanel.Title = 'Settings for preprocessing';
            app.discard = uicheckbox(app.SettingsforpreprocessingPanel);
            app.discard.Text = 'Discard bright areas';
            app.denoise = uicheckbox(app.SettingsforpreprocessingPanel);
            app.denoise.Text = 'Denoising';
            app.show = uicheckbox(app.SettingsforpreprocessingPanel);
            app.show.Text = 'Show images';
            app.StartSegmentationButton = uibutton(app.UIFigure, 'push');
            app.StartSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentationButtonPushed, true);
            app.StartSegmentationButton.Text = 'Start Segmentation';
            app.IterativeThresholdingPanel = uipanel(app.UIFigure);
            app.IterativeThresholdingPanel.Title = 'Iterative Thresholding';
            app.NumberofiterationsEditFieldLabel = uilabel(app.IterativeThresholdingPanel);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations ';
            app.nbIt = uieditfield(app.IterativeThresholdingPanel, 'numeric');
            app.nbIt.Limits = [1 10];
            app.nbIt.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadExampleImageButton = uibutton(app.UIFigure, 'push');
            app.LoadExampleImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExampleImageButtonPushed, true);
            app.LoadExampleImageButton.Text = 'Load Example Image';
            app.checkiterationButton = uibutton(app.UIFigure, 'push');
            app.checkiterationButton.ButtonPushedFcn = createCallbackFcn(app, @checkiterationButtonPushed, true);
            app.checkiterationButton.Text = 'check iteration';
            app.UIAxes_2 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SynEval_GUIbeta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end