classdef SynEval_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SettingsforPunctaSizeAdjustmentPanel  matlab.ui.container.Panel
        CameraPixelSizeinmEditFieldLabel  matlab.ui.control.Label
        CamPxlSiz                      matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        Bin                            matlab.ui.control.NumericEditField
        MaximumSizeinmEditFieldLabel   matlab.ui.control.Label
        MaxSiz                         matlab.ui.control.NumericEditField
        MinimumSizeinmEditFieldLabel   matlab.ui.control.Label
        MinSiz                         matlab.ui.control.NumericEditField
        MagnificationEditFieldLabel    matlab.ui.control.Label
        Mag                            matlab.ui.control.NumericEditField
        SettingsforpreprocessingPanel  matlab.ui.container.Panel
        discard                        matlab.ui.control.CheckBox
        denoise                        matlab.ui.control.CheckBox
        show                           matlab.ui.control.CheckBox
        StartSegmentationButton        matlab.ui.control.Button
        IterativeThresholdingPanel     matlab.ui.container.Panel
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        nbIt                           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        LoadExampleImageButton         matlab.ui.control.Button
        checkiterationButton           matlab.ui.control.Button
    end


    properties (Access = private)
        Preprocessing % Description
        Iteration
        SizeSettings
       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.Preprocessing, app.Iteration, app.SizeSettings] = configuration();
        end

        % Button pushed function: StartSegmentationButton
        function StartSegmentationButtonPushed(app, event)
            app.Preprocessing.Discard = app.discard.Value;
            app.Preprocessing.Denoise = app.denoise.Value;
            app.Preprocessing.ShowImage = app.show.Value;
            app.Iteration.it = app.nbIt.Value;   
            app.SizeSettings.CamPixelSize = app.CamPxlSiz.Value;
            app.SizeSettings.Magnification = app.Mag.Value;
            app.SizeSettings.Binning = app.Bin.Value;
            app.SizeSettings.MaxSize = app.MaxSiz.Value;
            app.SizeSettings.MinSize = app.MinSiz.Value;
            
            [resultCell] = SynEval_v1_1(app.Preprocessing, app.Iteration, app.SizeSettings);
            
        end

        % Button pushed function: LoadExampleImageButton
        function LoadExampleImageButtonPushed(app, event)
            [testInfo, testPath] = uigetfile('*.tif','select testimage','MultiSelect','off');
            testComplete = fullfile(testPath,testInfo);
            if ~iscell(testComplete)
                testComplete = cellstr(testComplete);
            end
            imgTest = double(imread(testComplete{1,1}));
            imshow(imgTest,'DisplayRange',[min(imgTest(:)) max(imgTest(:))], 'Parent', app.UIAxes)            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SettingsforPunctaSizeAdjustmentPanel = uipanel(app.UIFigure);
            app.SettingsforPunctaSizeAdjustmentPanel.Title = 'Settings for Puncta Size Adjustment';
            app.CameraPixelSizeinmEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.CameraPixelSizeinmEditFieldLabel.Text = 'Camera Pixel Size in µm';
            app.CamPxlSiz = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.CamPxlSiz.Limits = [0 100];
            app.CamPxlSiz.Value = 13;
            app.BinningEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.Bin = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.Bin.Limits = [1 4];
            app.Bin.Value = 1;
            app.MaximumSizeinmEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.MaximumSizeinmEditFieldLabel.Text = 'Maximum Size in µm';
            app.MaxSiz = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.MaxSiz.Limits = [0.01 100];
            app.MaxSiz.Value = 3;
            app.MinimumSizeinmEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.MinimumSizeinmEditFieldLabel.Text = 'Minimum Size in µm';
            app.MinSiz = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.MinSiz.Value = 0.5;
            app.MagnificationEditFieldLabel = uilabel(app.SettingsforPunctaSizeAdjustmentPanel);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.Mag = uieditfield(app.SettingsforPunctaSizeAdjustmentPanel, 'numeric');
            app.Mag.Limits = [1 100];
            app.Mag.Value = 60;
            app.SettingsforpreprocessingPanel = uipanel(app.UIFigure);
            app.SettingsforpreprocessingPanel.Title = 'Settings for preprocessing';
            app.discard = uicheckbox(app.SettingsforpreprocessingPanel);
            app.discard.Text = 'Discard bright areas';
            app.denoise = uicheckbox(app.SettingsforpreprocessingPanel);
            app.denoise.Text = 'Denoising';
            app.show = uicheckbox(app.SettingsforpreprocessingPanel);
            app.show.Text = 'Show images';
            app.StartSegmentationButton = uibutton(app.UIFigure, 'push');
            app.StartSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentationButtonPushed, true);
            app.StartSegmentationButton.Text = 'Start Segmentation';
            app.IterativeThresholdingPanel = uipanel(app.UIFigure);
            app.IterativeThresholdingPanel.Title = 'Iterative Thresholding';
            app.NumberofiterationsEditFieldLabel = uilabel(app.IterativeThresholdingPanel);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations ';
            app.nbIt = uieditfield(app.IterativeThresholdingPanel, 'numeric');
            app.nbIt.Limits = [1 10];
            app.nbIt.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadExampleImageButton = uibutton(app.UIFigure, 'push');
            app.LoadExampleImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExampleImageButtonPushed, true);
            app.LoadExampleImageButton.Text = 'Load Example Image';
            app.checkiterationButton = uibutton(app.UIFigure, 'push');
            app.checkiterationButton.Text = 'check iteration';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SynEval_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end